classdef Generate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        Chanel1Button    matlab.ui.control.StateButton
        Panel            matlab.ui.container.Panel
        AMFMLabel        matlab.ui.control.Label
        Label_15         matlab.ui.control.Label
        para_3           matlab.ui.control.NumericEditField
        para_2           matlab.ui.control.NumericEditField
        freUnit_3        matlab.ui.control.DropDown
        freUnit_2        matlab.ui.control.DropDown
        DropDown         matlab.ui.control.DropDown
        Label_14         matlab.ui.control.Label
        Label_13         matlab.ui.control.Label
        Label_12         matlab.ui.control.Label
        Label_11         matlab.ui.control.Label
        LabelPara        matlab.ui.control.Label
        para             matlab.ui.control.NumericEditField
        Label_5          matlab.ui.control.Label
        freUnit          matlab.ui.control.DropDown
        fre1             matlab.ui.control.NumericEditField
        Label_3          matlab.ui.control.Label
        VolUnit          matlab.ui.control.DropDown
        VolEditField1    matlab.ui.control.NumericEditField
        Label_2          matlab.ui.control.Label
        typeDropDown     matlab.ui.control.DropDown
        Label            matlab.ui.control.Label
        Chanel1Lamp      matlab.ui.control.Lamp
        RightPanel       matlab.ui.container.Panel
        Chanel2Button    matlab.ui.control.StateButton
        Chanel1Lamp_2    matlab.ui.control.Lamp
        Panel_2          matlab.ui.container.Panel
        AMFMLabel_2      matlab.ui.control.Label
        Label_23         matlab.ui.control.Label
        para_6           matlab.ui.control.NumericEditField
        para_5           matlab.ui.control.NumericEditField
        freUnit_6        matlab.ui.control.DropDown
        freUnit_5        matlab.ui.control.DropDown
        DropDown_2       matlab.ui.control.DropDown
        DropDown_2Label  matlab.ui.control.Label
        Label_22         matlab.ui.control.Label
        Label_21         matlab.ui.control.Label
        Label_20         matlab.ui.control.Label
        LabelPara_2      matlab.ui.control.Label
        para_4           matlab.ui.control.NumericEditField
        Label_19         matlab.ui.control.Label
        freUnit_4        matlab.ui.control.DropDown
        fre1_2           matlab.ui.control.NumericEditField
        Label_18         matlab.ui.control.Label
        VolUnit_2        matlab.ui.control.DropDown
        VolEditField1_2  matlab.ui.control.NumericEditField
        Label_17         matlab.ui.control.Label
        typeDropDown_2   matlab.ui.control.DropDown
        Label_16         matlab.ui.control.Label
        Panel_3          matlab.ui.container.Panel
        Label_25         matlab.ui.control.Label
        Label_24         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        dg4000
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dg4000 = DG4000("USB0::0x1AB1::0x0644::DG2P223500386::INSTR"); % ID必须手动修改
            app.dg4000.open()
            app.dg4000.Channel(1,0);
            app.dg4000.Channel(2,0);
        end

        % Value changed function: Chanel1Button
        function Chanel1ButtonValueChanged(app, event)
            app.dg4000.Channel(1,app.Chanel1Button.Value);

            if app.Chanel1Button.Value
                
                app.Chanel1Lamp.Color = [0 1 0];
            else
                
                app.Chanel1Lamp.Color = [1 0 0];
            end
            
            
        end

        % Value changed function: VolEditField1
        function VolEditField1ValueChanged(app, event)
            % 电压
            vol = app.VolEditField1.Value;

            switch app.VolUnit.Value
                case "mV"
                    vol = vol*10^(-3);
                case "uV"
                    vol = vol*10^(-6);
            end

            app.dg4000.SetVol(1,vol);
            
        end

        % Value changed function: fre1
        function fre1ValueChanged(app, event)
            % 设定频率
            freq = app.fre1.Value;
    
            switch app.freUnit.Value
                case "KHz"
                    freq = freq*10^(3);
                case "MHz"
                    freq = freq*10^(6);
            end

            app.dg4000.Setfreq(1,freq);
            
        end

        % Value changed function: typeDropDown
        function typeDropDownValueChanged(app, event)
            value = app.typeDropDown.Value;
            % wavename: |RAMP|PULSe||SINC|SQU
            % Shape(obj,source,type)
            switch value
                case "正弦波"
                    app.LabelPara.Text = "相位【单位degree】";
                    app.dg4000.Shape(1,"SIN");
                case "三角波"
                    app.LabelPara.Text = "对称性【单位%】";
                    app.dg4000.Shape(1,"RAMP");
                case "方波"
                    app.LabelPara.Text = "占空比【单位%】";
                    app.dg4000.Shape(1,"SQU");
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.dg4000.close()
            delete(app)            
        end

        % Value changed function: para
        function paraValueChanged(app, event)
            value = app.para.Value;
            switch app.typeDropDown.Value
                case "正弦波"
                    app.dg4000.Setphase(1,value);
                case "三角波"
                    app.dg4000.SetRamp(1,value);
                case "方波"
                    app.dg4000.SetSquare(1,value);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            switch app.DropDown.Value
                case "不调制"
                    app.dg4000.setMod(1,0);
                case "AM"
                    app.dg4000.setMod(1,1,"AM");
                case "FM"
                    app.dg4000.setMod(1,1,"FM")
            end
            
        end

        % Value changed function: para_2
        function para_2ValueChanged(app, event)
            f = app.para_2.Value;
            switch app.freUnit_2.Value
                case "KHz"
                    f = f*10^(3);
                case "MHz"
                    f = f*10^(6);
            end
            if app.DropDown.Value == "AM"
                % 设置AM调制波的频率，单位默认为“Hz”。
                % eg::MOD:AM:INTernal:FREQuency 25
                fprintf(app.dg4000.vis,strcat(":SOURce1:MOD:AM:INTernal:FREQuency ",num2str(f)));
                
            elseif app.DropDown.Value == "FM"
                % 设置频偏,单位为HZ，默认1kHz
                % eg::MOD:FM 800
                fprintf(app.dg4000.vis,strcat(":SOURce1:MOD:FM:INTernal:FREQuency ",num2str(f)));
            end
        end

        % Value changed function: para_3
        function para_3ValueChanged(app, event)
            value = app.para_3.Value;
            if app.DropDown.Value == "AM"
                % 设置调制深度
                % eg::MOD:AM 80
                fprintf(app.dg4000.vis,strcat(":SOURce1:MOD:AM ",num2str(value)));

            elseif app.DropDown.Value == "FM"
                 % 设置频偏,单位为HZ，默认1kHz
                % eg::MOD:FM 800
                switch app.freUnit_3.Value
                    case "KHz"
                        value = value*10^(3);
                    case "MHz"
                        value = value*10^(6);
                end
                fprintf(app.dg4000.vis,strcat(":SOURce1:MOD:FM ",num2str(value)));                
            end
        end

        % Value changed function: typeDropDown_2
        function typeDropDown_2ValueChanged(app, event)
            value = app.typeDropDown_2.Value;
            % wavename: |RAMP|PULSe||SINC|SQU
            % Shape(obj,source,type)
            switch value
                case "正弦波"
                    app.LabelPara_2.Text = "相位【单位degree】";
                    app.dg4000.Shape(2,"SIN");
                case "三角波"
                    app.LabelPara_2.Text = "对称性【单位%】";
                    app.dg4000.Shape(2,"RAMP");
                case "方波"
                    app.LabelPara_2.Text = "占空比【单位%】";
                    app.dg4000.Shape(2,"SQU");
            end
            
        end

        % Value changed function: VolEditField1_2
        function VolEditField1_2ValueChanged(app, event)
            % 电压
            vol = app.VolEditField1_2.Value;

            switch app.VolUnit_2.Value
                case "mV"
                    vol = vol*10^(-3);
                case "uV"
                    vol = vol*10^(-6);
            end

            app.dg4000.SetVol(2,vol);
            
        end

        % Value changed function: fre1_2
        function fre1_2ValueChanged(app, event)
            % 设定频率
            freq = app.fre1_2.Value;
    
            switch app.freUnit_4.Value
                case "KHz"
                    freq = freq*10^(3);
                case "MHz"
                    freq = freq*10^(6);
            end

            app.dg4000.Setfreq(2,freq);
            
        end

        % Value changed function: para_4
        function para_4ValueChanged(app, event)
            value = app.para_4.Value;
            switch app.typeDropDown_2.Value
                case "正弦波"
                    app.dg4000.Setphase(2,value);
                case "三角波"
                    app.dg4000.SetRamp(2,value);
                case "方波"
                    app.dg4000.SetSquare(2,value);
            end
            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            switch app.DropDown_2.Value
                case "不调制"
                    app.dg4000.setMod(2,0);
                case "AM"
                    app.dg4000.setMod(2,1,"AM");
                case "FM"
                    app.dg4000.setMod(2,1,"FM")
            end
            
        end

        % Value changed function: para_5
        function para_5ValueChanged(app, event)
            f = app.para_5.Value;
            switch app.freUnit_5.Value
                case "KHz"
                    f = f*10^(3);
                case "MHz"
                    f = f*10^(6);
            end
            if app.DropDown_2.Value == "AM"
                % 设置AM调制波的频率，单位默认为“Hz”。
                % eg::MOD:AM:INTernal:FREQuency 25
                fprintf(app.dg4000.vis,strcat(":SOURce2:MOD:AM:INTernal:FREQuency ",num2str(f)));
                
            elseif app.DropDown_2.Value == "FM"
                % 设置频偏,单位为HZ，默认1kHz
                % eg::MOD:FM 800
                fprintf(app.dg4000.vis,strcat(":SOURce2:MOD:FM:INTernal:FREQuency ",num2str(f)));
            end
            
        end

        % Value changed function: para_6
        function para_6ValueChanged(app, event)
            value = app.para_6.Value;
            if app.DropDown_2.Value == "AM"
                % 设置调制深度
                % eg::MOD:AM 80
                fprintf(app.dg4000.vis,strcat(":SOURce2:MOD:AM ",num2str(value)));

            elseif app.DropDown_2.Value == "FM"
                 % 设置频偏,单位为HZ，默认1kHz
                % eg::MOD:FM 800
                switch app.freUnit_6.Value
                    case "KHz"
                        value = value*10^(3);
                    case "MHz"
                        value = value*10^(6);
                end
                fprintf(app.dg4000.vis,strcat(":SOURce2:MOD:FM ",num2str(value)));                
            end
            
        end

        % Value changed function: Chanel2Button
        function Chanel2ButtonValueChanged(app, event)
            app.dg4000.Channel(2,app.Chanel2Button.Value);

            if app.Chanel2Button.Value
                
                app.Chanel1Lamp_2.Color = [0 1 0];
            else
                
                app.Chanel1Lamp_2.Color = [1 0 0];
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {635, 635};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {389, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Chanel1Lamp = uilamp(app.LeftPanel);
            app.Panel = uipanel(app.LeftPanel);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '波形';
            app.typeDropDown = uidropdown(app.Panel);
            app.typeDropDown.Items = {'正弦波', '方波', '三角波'};
            app.typeDropDown.ValueChangedFcn = createCallbackFcn(app, @typeDropDownValueChanged, true);
            app.typeDropDown.Value = '正弦波';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '电压峰峰值';
            app.VolEditField1 = uieditfield(app.Panel, 'numeric');
            app.VolEditField1.ValueChangedFcn = createCallbackFcn(app, @VolEditField1ValueChanged, true);
            app.VolUnit = uidropdown(app.Panel);
            app.VolUnit.Items = {'V', 'mV', 'uV'};
            app.VolUnit.Value = 'V';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '频率';
            app.fre1 = uieditfield(app.Panel, 'numeric');
            app.fre1.ValueChangedFcn = createCallbackFcn(app, @fre1ValueChanged, true);
            app.freUnit = uidropdown(app.Panel);
            app.freUnit.Items = {'Hz', 'KHz', 'MHz'};
            app.freUnit.Value = 'Hz';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = {''; ''};
            app.para = uieditfield(app.Panel, 'numeric');
            app.para.ValueChangedFcn = createCallbackFcn(app, @paraValueChanged, true);
            app.LabelPara = uilabel(app.Panel);
            app.LabelPara.Text = '相位';
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = '';
            app.Label_12 = uilabel(app.Panel);
            app.Label_12.Text = '调制选项【1】';
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = '基本设置【1】';
            app.Label_14 = uilabel(app.Panel);
            app.Label_14.Text = '调制方式';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'不调制', 'AM', 'FM'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '不调制';
            app.freUnit_2 = uidropdown(app.Panel);
            app.freUnit_2.Items = {'Hz', 'KHz', 'MHz'};
            app.freUnit_2.Value = 'Hz';
            app.freUnit_3 = uidropdown(app.Panel);
            app.freUnit_3.Items = {'Hz', 'KHz', 'MHz'};
            app.freUnit_3.Value = 'Hz';
            app.para_2 = uieditfield(app.Panel, 'numeric');
            app.para_2.ValueChangedFcn = createCallbackFcn(app, @para_2ValueChanged, true);
            app.para_3 = uieditfield(app.Panel, 'numeric');
            app.para_3.ValueChangedFcn = createCallbackFcn(app, @para_3ValueChanged, true);
            app.Label_15 = uilabel(app.Panel);
            app.Label_15.Text = '基带频率';
            app.AMFMLabel = uilabel(app.Panel);
            app.AMFMLabel.Text = {'调制深度【AM】'; '最大频偏【FM】'};
            app.Chanel1Button = uibutton(app.LeftPanel, 'state');
            app.Chanel1Button.ValueChangedFcn = createCallbackFcn(app, @Chanel1ButtonValueChanged, true);
            app.Chanel1Button.Text = 'Chanel1';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel_2 = uipanel(app.RightPanel);
            app.Label_16 = uilabel(app.Panel_2);
            app.Label_16.Text = '波形';
            app.typeDropDown_2 = uidropdown(app.Panel_2);
            app.typeDropDown_2.Items = {'正弦波', '方波', '三角波'};
            app.typeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @typeDropDown_2ValueChanged, true);
            app.typeDropDown_2.Value = '正弦波';
            app.Label_17 = uilabel(app.Panel_2);
            app.Label_17.Text = '电压峰峰值';
            app.VolEditField1_2 = uieditfield(app.Panel_2, 'numeric');
            app.VolEditField1_2.ValueChangedFcn = createCallbackFcn(app, @VolEditField1_2ValueChanged, true);
            app.VolUnit_2 = uidropdown(app.Panel_2);
            app.VolUnit_2.Items = {'V', 'mV', 'uV'};
            app.VolUnit_2.Value = 'V';
            app.Label_18 = uilabel(app.Panel_2);
            app.Label_18.Text = '频率';
            app.fre1_2 = uieditfield(app.Panel_2, 'numeric');
            app.fre1_2.ValueChangedFcn = createCallbackFcn(app, @fre1_2ValueChanged, true);
            app.freUnit_4 = uidropdown(app.Panel_2);
            app.freUnit_4.Items = {'Hz', 'KHz', 'MHz'};
            app.freUnit_4.Value = 'Hz';
            app.Label_19 = uilabel(app.Panel_2);
            app.Label_19.Text = {''; ''};
            app.para_4 = uieditfield(app.Panel_2, 'numeric');
            app.para_4.ValueChangedFcn = createCallbackFcn(app, @para_4ValueChanged, true);
            app.LabelPara_2 = uilabel(app.Panel_2);
            app.LabelPara_2.Text = '相位';
            app.Label_20 = uilabel(app.Panel_2);
            app.Label_20.Text = '';
            app.Label_21 = uilabel(app.Panel_2);
            app.Label_21.Text = '调制选项【2】';
            app.Label_22 = uilabel(app.Panel_2);
            app.Label_22.Text = '基本设置【2】';
            app.DropDown_2Label = uilabel(app.Panel_2);
            app.DropDown_2Label.Text = '调制方式';
            app.DropDown_2 = uidropdown(app.Panel_2);
            app.DropDown_2.Items = {'不调制', 'AM', 'FM'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '不调制';
            app.freUnit_5 = uidropdown(app.Panel_2);
            app.freUnit_5.Items = {'Hz', 'KHz', 'MHz'};
            app.freUnit_5.Value = 'Hz';
            app.freUnit_6 = uidropdown(app.Panel_2);
            app.freUnit_6.Items = {'Hz', 'KHz', 'MHz'};
            app.freUnit_6.Value = 'Hz';
            app.para_5 = uieditfield(app.Panel_2, 'numeric');
            app.para_5.ValueChangedFcn = createCallbackFcn(app, @para_5ValueChanged, true);
            app.para_6 = uieditfield(app.Panel_2, 'numeric');
            app.para_6.ValueChangedFcn = createCallbackFcn(app, @para_6ValueChanged, true);
            app.Label_23 = uilabel(app.Panel_2);
            app.Label_23.Text = '基带频率';
            app.AMFMLabel_2 = uilabel(app.Panel_2);
            app.AMFMLabel_2.Text = {'调制深度【AM】'; '最大频偏【FM】'};
            app.Chanel1Lamp_2 = uilamp(app.RightPanel);
            app.Chanel2Button = uibutton(app.RightPanel, 'state');
            app.Chanel2Button.ValueChangedFcn = createCallbackFcn(app, @Chanel2ButtonValueChanged, true);
            app.Chanel2Button.Text = 'Chanel2';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Label_24 = uilabel(app.Panel_3);
            app.Label_24.Text = '';
            app.Label_25 = uilabel(app.Panel_3);
            app.Label_25.Text = '双通道波形发生器';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Generate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end