classdef scope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        displayPanel      matlab.ui.container.Panel
        OPENLabel         matlab.ui.control.Label
        CLOSELabel        matlab.ui.control.Label
        Switch            matlab.ui.control.Switch
        timeAxes          matlab.ui.control.UIAxes
        controlPanel      matlab.ui.container.Panel
        Image4            matlab.ui.control.Image
        verticalLabel     matlab.ui.control.Label
        HorizontalLabel   matlab.ui.control.Label
        MENULabel         matlab.ui.control.Label
        Panel_3           matlab.ui.container.Panel
        ScreenshotButton  matlab.ui.control.Button
        HelpButton        matlab.ui.control.Button
        autoUpdataButton  matlab.ui.control.StateButton
        ClearButton       matlab.ui.control.Button
        AdvancedButton    matlab.ui.control.Button
        RunStopButton     matlab.ui.control.StateButton
        UpdataButton      matlab.ui.control.Button
        AutoButton        matlab.ui.control.Button
        Panel_2           matlab.ui.container.Panel
        Image3_2          matlab.ui.control.Image
        rightPosButton    matlab.ui.control.Button
        leftPosButton     matlab.ui.control.Button
        Image3            matlab.ui.control.Image
        rightScaleButton  matlab.ui.control.Button
        leftScaleButton   matlab.ui.control.Button
        Panel             matlab.ui.container.Panel
        v2ScaleKnob       matlab.ui.control.Knob
        v1ScaleKnob       matlab.ui.control.Knob
        v2PositonKnob     matlab.ui.control.Knob
        Image2            matlab.ui.control.Image
        Image             matlab.ui.control.Image
        resetButton       matlab.ui.control.Button
        CH2Button         matlab.ui.control.StateButton
        CH1Button         matlab.ui.control.StateButton
        v1PositonKnob     matlab.ui.control.Knob
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        ds2020a % Description
        T
        data1 = [];
        data2 = [];

    end
    
    methods (Access = public)
        
        function disp(app)
          % 绘图函数
            if app.CH1Button.Value 
                [t1,data1]=app.ds2020a.Get.ChanelDisp('CHANnel1');
                plot(app.timeAxes, t1, data1);
                app.data1 = data1;
                app.T=t1(2)-t1(1);
            end
            if app.CH2Button.Value 
                [t2,data2]=app.ds2020a.Get.ChanelDisp('CHANnel2');
                hold on
                plot(app.timeAxes, t2, data2);
                app.data2 = data2;
                app.T=t2(2)-t2(1);
            end
            hold off           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         % ========================== 端口地址应该手动修改  ================================
            app.ds2020a = DS2020A("USB0::0x1AB1::0x04B0::DS2D223401445::INSTR");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {695, 695};
                app.GridLayout.ColumnWidth = {'1x'};
                app.controlPanel.Layout.Row = 2;
                app.controlPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {821, '1x'};
                app.controlPanel.Layout.Row = 1;
                app.controlPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function opencloseSwitchValueChanged(app, event)
            value = app.opencloseSwitch.Value;
            if value == "on"
                app.ds2020a.open()
            else
                app.ds2020a.close()
            end
        end

        % Callback function
        function OpenCloseButtonValueChanged(app, event)
            value = app.OpenCloseButton.Value;
            if value
                app.ds2020a.open()
            else
                app.ds2020a.close()
            end
        end

        % Value changed function: RunStopButton
        function RunStopButtonValueChanged(app, event)
            value = app.RunStopButton.Value;
            if value
                app.ds2020a.Set.run()
            else
                app.ds2020a.Set.stop()
            end
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            app.ds2020a.Set.autoScale()
            pause(0.3);
            if app.autoUpdataButton.Value 
               app.disp()
           end
        end

        % Value changed function: CH1Button
        function CH1ButtonValueChanged(app, event)
            value = app.CH1Button.Value;
            if value 
                app.ds2020a.Set.channelDisp(1,value)
                app.CH1Button.FontColor = [1,0,0];
            else
                app.CH1Button.FontColor = [0.65,0.65,0.65];
            end
        end

        % Value changed function: CH2Button
        function CH2ButtonValueChanged(app, event)
            value = app.CH2Button.Value;
            if value 
                app.ds2020a.Set.channelDisp(2,value);
                app.CH2Button.FontColor = [1,0,0];
            else
                app.CH2Button.FontColor = [0.65,0.65,0.65];
            end
        end

        % Button pushed function: UpdataButton
        function UpdataButtonPushed(app, event)
            app.disp()            
        end

        % Button pushed function: leftScaleButton
        function leftScaleButtonPushed(app, event)
            app.ds2020a.Set.turnKnob("HSCale",0);
            pause(0.5);
            if app.autoUpdataButton.Value 
               app.disp()
           end
        end

        % Button pushed function: rightScaleButton
        function rightScaleButtonPushed(app, event)
            app.ds2020a.Set.turnKnob("HSCale",1);
            pause(0.5);
            if app.autoUpdataButton.Value 
               app.disp()
           end
        end

        % Button pushed function: leftPosButton
        function leftPosButtonPushed(app, event)
             app.ds2020a.Set.turnKnob("HPOSition",0);
             pause(0.3);
             if app.autoUpdataButton.Value 
               app.disp()
             end
        end

        % Button pushed function: rightPosButton
        function rightPosButtonPushed(app, event)
            app.ds2020a.Set.turnKnob("HPOSition",1);
            pause(0.3);
            if app.autoUpdataButton.Value 
               app.disp()
           end
        end

        % Value changed function: v1PositonKnob
        function v1PositonKnobValueChanged(app, event)
            value = event.Value;
            oldValue = event.PreviousValue;
            times = abs(value - oldValue);
            if value>= oldValue
                %顺时针旋转，值增大
                for n= 1:times
                    app.ds2020a.Set.turnKnob("VPOSition1",1);
                end
            else
                %逆时针旋转，值增大
                for n= 1:times
                    app.ds2020a.Set.turnKnob("VPOSition1",0);
                end
            end
           pause(0.3);
           if app.autoUpdataButton.Value 
               app.disp()
           end
         
        end

        % Value changed function: v1ScaleKnob
        function v1ScaleKnobValueChanged(app, event)
            value = event.Value;
            oldValue = event.PreviousValue;
            times = abs(value - oldValue);
            if value>= oldValue
                %顺时针旋转，值增大
                for n= 1:times
                    app.ds2020a.Set.turnKnob("VSCale1",1);
                end
            else
                %逆时针旋转，值增大
                for n= 1:times
                    app.ds2020a.Set.turnKnob("VSCale1",0);
                end
            end
            pause(0.3);
            if app.autoUpdataButton.Value 
               app.disp()
           end
           
        end

        % Value changed function: v2ScaleKnob
        function v2ScaleKnobValueChanged(app, event)
            value = event.Value;
            oldValue = event.PreviousValue;
            times = abs(value - oldValue);
            if value>= oldValue
                %顺时针旋转，值增大
                for n= 1:times
                    app.ds2020a.Set.turnKnob("VSCale2",1);
                end
            else
                %逆时针旋转，值增大
                for n= 1:times
                    app.ds2020a.Set.turnKnob("VSCale2",0);
                end
            end
            pause(0.3);
            if app.autoUpdataButton.Value 
               app.disp()
           end
           
            
        end

        % Value changed function: v2PositonKnob
        function v2PositonKnobValueChanged(app, event)
            value = event.Value;
            oldValue = event.PreviousValue;
            times = abs(value - oldValue);
            if value>= oldValue
                %顺时针旋转，值增大
                for n= 1:times
                    app.ds2020a.Set.turnKnob("VPOSition2",1);
                end
            else
                %逆时针旋转，值增大
                for n= 1:times
                    app.ds2020a.Set.turnKnob("VPOSition2",0);
                end
            end
            pause(0.3);
            if app.autoUpdataButton.Value 
               app.disp()
           end
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            %关闭旋钮的使能项，防止重置时触发回调
            app.v1PositonKnob.Enable = 'off';
            app.v2PositonKnob.Enable = 'off';
            app.v1ScaleKnob.Enable = 'off';
            app.v2ScaleKnob.Enable = 'off';
            %各旋钮均置零
            app.v1PositonKnob.Value = 0;
            app.v2PositonKnob.Value = 0;
            app.v1ScaleKnob.Value = 0;
            app.v2ScaleKnob.Value = 0;
            
            %打开旋钮的使能项
            app.v1PositonKnob.Enable = 'on';
            app.v2PositonKnob.Enable = 'on';
            app.v1ScaleKnob.Enable = 'on';
            app.v2ScaleKnob.Enable = 'on';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ds2020a.Set.clear();
            pause(0.3);
            if app.autoUpdataButton.Value 
               app.disp()
           end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            GUI = HelpGUI();
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "OPEN"
                app.ds2020a.open()
                app.CLOSELabel.FontColor = [0.65,0.65,0.65];
                app.OPENLabel.FontColor = [1,0,0];
            else
                app.ds2020a.close()
                app.CLOSELabel.FontColor = [1,0,0];
                app.OPENLabel.FontColor = [0.65,0.65,0.65];
            end
        end

        % Value changed function: autoUpdataButton
        function autoUpdataButtonValueChanged(app, event)
            value = app.autoUpdataButton.Value;
            if value
                app.autoUpdataButton.FontColor = [1,0,0];
            else
                app.autoUpdataButton.FontColor = [0,0,0];
            end
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)
             if app.CH1Button.Value  && app.CH2Button.Value
                signalAnalyzer(app.data1,app.data2,'SampleTime',app.T);
             elseif app.CH1Button.Value && ~app.CH2Button.Value
                 signalAnalyzer(app.data1,'SampleTime',app.T);
             elseif ~app.CH1Button.Value && app.CH2Button.Value
                 signalAnalyzer(app.data2,'SampleTime',app.T);                
            end
        end

        % Button pushed function: ScreenshotButton
        function ScreenshotButtonPushed(app, event)
            open("C:\Program Files (x86)\RIGOL\Ultra Sigma\Ultra Sigma.exe");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.displayPanel = uipanel(app.GridLayout);
            app.timeAxes = uiaxes(app.displayPanel);
            title(app.timeAxes, '时域')
            xlabel(app.timeAxes, 'X')
            ylabel(app.timeAxes, 'Y')
            app.Switch = uiswitch(app.displayPanel, 'slider');
            app.Switch.Items = {'CLOSE', 'OPEN'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'CLOSE';
            app.CLOSELabel = uilabel(app.displayPanel);
            app.CLOSELabel.Text = 'CLOSE';
            app.OPENLabel = uilabel(app.displayPanel);
            app.OPENLabel.Text = 'OPEN';
            app.controlPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.controlPanel);
            app.v1PositonKnob = uiknob(app.Panel, 'continuous');
            app.v1PositonKnob.Limits = [-10 10];
            app.v1PositonKnob.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.v1PositonKnob.ValueChangedFcn = createCallbackFcn(app, @v1PositonKnobValueChanged, true);
            app.v1PositonKnob.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.CH1Button = uibutton(app.Panel, 'state');
            app.CH1Button.ValueChangedFcn = createCallbackFcn(app, @CH1ButtonValueChanged, true);
            app.CH1Button.Text = 'CH1';
            app.CH2Button = uibutton(app.Panel, 'state');
            app.CH2Button.ValueChangedFcn = createCallbackFcn(app, @CH2ButtonValueChanged, true);
            app.CH2Button.Text = 'CH2';
            app.resetButton = uibutton(app.Panel, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'knob reset';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'position.jpg';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'scale.jpg';
            app.v2PositonKnob = uiknob(app.Panel, 'continuous');
            app.v2PositonKnob.Limits = [-10 10];
            app.v2PositonKnob.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.v2PositonKnob.ValueChangedFcn = createCallbackFcn(app, @v2PositonKnobValueChanged, true);
            app.v2PositonKnob.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.v1ScaleKnob = uiknob(app.Panel, 'continuous');
            app.v1ScaleKnob.Limits = [-10 10];
            app.v1ScaleKnob.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.v1ScaleKnob.ValueChangedFcn = createCallbackFcn(app, @v1ScaleKnobValueChanged, true);
            app.v1ScaleKnob.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.v2ScaleKnob = uiknob(app.Panel, 'continuous');
            app.v2ScaleKnob.Limits = [-10 10];
            app.v2ScaleKnob.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.v2ScaleKnob.ValueChangedFcn = createCallbackFcn(app, @v2ScaleKnobValueChanged, true);
            app.v2ScaleKnob.MinorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.Panel_2 = uipanel(app.controlPanel);
            app.leftScaleButton = uibutton(app.Panel_2, 'push');
            app.leftScaleButton.ButtonPushedFcn = createCallbackFcn(app, @leftScaleButtonPushed, true);
            app.leftScaleButton.Icon = 'left3.jpg';
            app.leftScaleButton.Text = '';
            app.rightScaleButton = uibutton(app.Panel_2, 'push');
            app.rightScaleButton.ButtonPushedFcn = createCallbackFcn(app, @rightScaleButtonPushed, true);
            app.rightScaleButton.Icon = 'right.jpg';
            app.rightScaleButton.Text = '';
            app.Image3 = uiimage(app.Panel_2);
            app.Image3.ImageSource = 'scale3.jpg';
            app.leftPosButton = uibutton(app.Panel_2, 'push');
            app.leftPosButton.ButtonPushedFcn = createCallbackFcn(app, @leftPosButtonPushed, true);
            app.leftPosButton.Icon = 'left3.jpg';
            app.leftPosButton.Text = '';
            app.rightPosButton = uibutton(app.Panel_2, 'push');
            app.rightPosButton.ButtonPushedFcn = createCallbackFcn(app, @rightPosButtonPushed, true);
            app.rightPosButton.Icon = 'right.jpg';
            app.rightPosButton.Text = '';
            app.Image3_2 = uiimage(app.Panel_2);
            app.Image3_2.ImageSource = 'pos.jpg';
            app.Panel_3 = uipanel(app.controlPanel);
            app.AutoButton = uibutton(app.Panel_3, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.UpdataButton = uibutton(app.Panel_3, 'push');
            app.UpdataButton.ButtonPushedFcn = createCallbackFcn(app, @UpdataButtonPushed, true);
            app.UpdataButton.Text = 'Updata';
            app.RunStopButton = uibutton(app.Panel_3, 'state');
            app.RunStopButton.ValueChangedFcn = createCallbackFcn(app, @RunStopButtonValueChanged, true);
            app.RunStopButton.Text = 'Run/Stop';
            app.AdvancedButton = uibutton(app.Panel_3, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced';
            app.ClearButton = uibutton(app.Panel_3, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.autoUpdataButton = uibutton(app.Panel_3, 'state');
            app.autoUpdataButton.ValueChangedFcn = createCallbackFcn(app, @autoUpdataButtonValueChanged, true);
            app.autoUpdataButton.Text = 'Auto Updata';
            app.HelpButton = uibutton(app.Panel_3, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ScreenshotButton = uibutton(app.Panel_3, 'push');
            app.ScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenshotButtonPushed, true);
            app.ScreenshotButton.Text = 'Screenshot';
            app.MENULabel = uilabel(app.controlPanel);
            app.MENULabel.Text = 'MENU';
            app.HorizontalLabel = uilabel(app.controlPanel);
            app.HorizontalLabel.Text = 'Horizontal';
            app.verticalLabel = uilabel(app.controlPanel);
            app.verticalLabel.Text = '  vertical';
            app.Image4 = uiimage(app.controlPanel);
            app.Image4.ImageSource = 'logo.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end